classdef Links_DOF < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UITable                      matlab.ui.control.Table
        NumberofLinksEditFieldLabel  matlab.ui.control.Label
        NumberofLinksEditField       matlab.ui.control.NumericEditField
        DOFEditFieldLabel            matlab.ui.control.Label
        DOFEditField                 matlab.ui.control.NumericEditField
        CalculateButton              matlab.ui.control.Button
        MaxLinksEditFieldLabel       matlab.ui.control.Label
        MaxLinksEditField            matlab.ui.control.NumericEditField
        Label                        matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
            L = app.NumberofLinksEditField.Value;
            M = app.DOFEditField.Value;
            
           if L >= M+3 
                % L = B + T + Q + P + H
                % M = B - Q - 2P - 3H -3
                % L - 3 - M = T + 2Q + 3P + 4H
                
                %numbers of links included
                x = 0:app.MaxLinksEditField.Value;
                
                %number of elements to pick (with repetetition) from x.
                n = 5;
                assert(n < numel(x), 'n can''t be greater than numel(x)')
                combs = cell(1, n);
                [combs{:}] = ndgrid(x);
                combs = reshape(cat(n+1, combs{:}), [], n);
                
                j=1;
                for i= 1:1:size(combs)
                    B = combs(i,1);
                    T = combs(i,2);
                    Q = combs(i,3);
                    P = combs(i,4);
                    H = combs(i,5);
                    
                    if (((B+T+Q+P+H) == L) && (M == (B-Q-2*P-3*H-3)) && ((L-3-M)== (T+2*Q+3*P+4*H)))
                        Links(j,:) = [B  T Q P H];
                        j = j + 1;
                    end
                end
           else
               Links = [0 0 0 0 0];
           end
            app.UITable.Data=Links;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'B'; 'T'; 'Q'; 'P'; 'H'};
            app.UITable.RowName = {};
            app.NumberofLinksEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofLinksEditFieldLabel.Text = 'Number of Links';
            app.NumberofLinksEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofLinksEditField.Tag = 'Links';
            app.DOFEditFieldLabel = uilabel(app.UIFigure);
            app.DOFEditFieldLabel.Text = 'DOF';
            app.DOFEditField = uieditfield(app.UIFigure, 'numeric');
            app.DOFEditField.Tag = 'DOF';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.MaxLinksEditFieldLabel = uilabel(app.UIFigure);
            app.MaxLinksEditFieldLabel.Text = 'Max Links';
            app.MaxLinksEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'If the output is all zeros, means there''s no combination that satisfies the conditions';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'The maximum number of links will be dependant on the users computer performance.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Links_DOF

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end